classdef app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        WeightresultTab             matlab.ui.container.Tab
        ScaleMaximumEditFieldLabel  matlab.ui.control.Label
        ScaleMaximumEditField       matlab.ui.control.NumericEditField
        ScaleMinimumEditFieldLabel  matlab.ui.control.Label
        ScaleMinimumEditField       matlab.ui.control.NumericEditField
        AutoscaleSwitchLabel        matlab.ui.control.Label
        AutoscaleSwitch             matlab.ui.control.Switch
        PhaseSliderLabel            matlab.ui.control.Label
        tWeightSlider               matlab.ui.control.Slider
        WeightSliderLabel           matlab.ui.control.Label
        mWeightSlider               matlab.ui.control.Slider
        SwitchRef                   matlab.ui.control.Switch
        ImportRawXlsFileButton      matlab.ui.control.Button
        mWeightLabel                matlab.ui.control.Label
        tWeightLabel                matlab.ui.control.Label
        ImporttableTab              matlab.ui.container.Tab
        ResulttableTab              matlab.ui.container.Tab
        P2PplotTab                  matlab.ui.container.Tab
        WeightsplotTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Pax % Resul polar plot
        Vax % Vibration polar plot
        Data % Data   
        RawData % Data from xls
        Box % Hint
        Type % All or reference
        uitImport % Description
    end
    
    methods (Access = private)
        
        function updateplot(app)
            global gcolor; setpath;
            mWeight = app.mWeightSlider.Value;
            tWeight = app.tWeightSlider.Value;
            zWeight = getcomplex(mWeight, tWeight);
            D = app.Data;
            modes = unique(D.modes);
            app.Pax.NextPlot = 'replace';
            for i = 1:length(modes)
                g = colormod(i);
                a = v_isakovich(D(i,:), zWeight);
                zP2P = getcomplex(D.mP2P(i), D.tP2P(i));
                points = arrowapp(zP2P, a);
                z = [linspace(zP2P, a, 10e3), points];
                polarplot(app.Pax, z, '.', ...
                    'LineWidth', 5, ...
                    'Color',  gcolor(g,:));
                app.Pax.NextPlot = 'add';
            end
            legend(app.Pax,cellstr(modes), ...
                'Location','northeastoutside');
        end
        
        function vibrationplot(app)
            global gcolor; setpath;
            D = app.RawData;
            modes = unique(D.mode);
            cnt = 0;
            app.Vax.NextPlot = 'replace';
            for i = 1:length(modes)
                g = colormod(i);
                m = D(D.mode == modes(i), :);
                for j = 1:height(m)
                    ref = m.ref(j);
                    if ref ~= -1
                        z = linspace(m.zP2P(ref), m.zP2P(j), 10e3);
                        %if cnt == 0 
                            
                            points = arrowapp(m.zP2P(ref),  m.zP2P(j));
                            z = [z, points];
                            polarplot(app.Vax, z, '.', ...
                                'LineWidth', 5, ...
                                'Color',  gcolor(g,:));
                            cnt = cnt + 1;
                            app.Vax.NextPlot = 'add';
                       % else
                             %polarplot(app.Vax, z, '.', ...
                           %     'LineWidth', 5, ...
                           %     'Color',  gcolor(g,:));
                          %  app.Vax.NextPlot = 'add';
                        %end
                    end
                end
            end
            legend(app.Vax,cellstr(modes), ...
                'Location','northeastoutside');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filepath)
            setpath;
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];   
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.WeightresultTab);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [150 40 width-220 height-120];
            app.Vax = polaraxes(app.P2PplotTab);
            app.Vax.Units = 'pixels';
            app.Vax.Position = [40 40 width-150 height-100];
            app.Type = 'all';
        end

        % Value changed function: mWeightSlider, tWeightSlider
        function SliderChange(app, event)
            app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
            app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');   
            if app.Box == 1
                updateplot(app);
            end
        end

        % Callback function
        function AutoscaleCheckBoxValueChanged(app, event)
            value = app.AutoscaleCheckBox.Value;         
            if ~value
                rl = get(app.Pax, 'RLim');
                visible(1);
                pos = app.ScaleMaximumEditField.Position;
                app.ScaleMaximumEditField.Position = [pos(1),pos(2),50,25];
                pos = app.ScaleMinimumEditField.Position;
                app.ScaleMinimumEditField.Position = [pos(1),pos(2),50,25];
                app.ScaleMaximumEditField.Value = rl(2);
                app.ScaleMinimumEditField.Value = rl(1);
            else
                visible(0);
                app.Pax.RLimMode = 'auto';
            end          
            function visible(tf)
                app.ScaleMaximumEditField.Visible = tf;
                app.ScaleMaximumEditFieldLabel.Visible = tf;
                app.ScaleMinimumEditField.Visible = tf;
                app.ScaleMinimumEditFieldLabel.Visible = tf;
            end           
        end

        % Value changed function: ScaleMaximumEditField
        function ScaleMaximumEditFieldValueChanged(app, event)
            maximum = app.ScaleMaximumEditField.Value;
            minimum = app.ScaleMinimumEditField.Value;
            if maximum > minimum
                rlim(app.Pax, [minimum maximum]);
            end
        end

        % Value changed function: ScaleMinimumEditField
        function ScaleMinimumEditFieldValueChanged(app, event)
            maximum = app.ScaleMaximumEditField.Value;
            minimum = app.ScaleMinimumEditField.Value;
            if maximum > minimum
                rlim(app.Pax, [minimum maximum]);            
            end
        end

        % Button pushed function: ImportRawXlsFileButton
        function ImportRawXlsFileButtonPushed(app, event)
            setpath;
            [file, path] = uigetfile({'*.xls'});
            if ~isequal(file,0)
                screenSize = get(groot,'ScreenSize');
                screenWidth = screenSize(3);
                screenHeight = screenSize(4);
                width = screenWidth*0.8;
                height = screenHeight*0.8;
                R = imxls(fullfile(path, file));
                T = pullcorrectweight(R, app.Type);
                app.Data = sumsen(T);
                app.RawData = R;
                app.mWeightSlider.Limits = [0 ceil(2*app.Data.mWeightTarget(1))];
                app.mWeightSlider.Value = app.Data.mWeightTarget(1);
                app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
                app.tWeightSlider.Value = wrapTo360(app.Data.tWeightTarget(1));
                app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');
                app.Box = 1;
                updateplot(app); 
                uitable(app.ImporttableTab, "Data", R, "Position", [50 50 width-100 height-100]);
                writetable(app.Data, fullfile('summary.xls'))
                uitable(app.ResulttableTab, "Data", T, "Position", [50 50 width-100 height-100]);
                vibrationplot(app);
            end  
        end

        % Value changed function: AutoscaleSwitch
        function AutoscaleSwitchValueChanged(app, event)
            value = app.AutoscaleSwitch.Value;
            if value ~= "On"
                rl = get(app.Pax, 'RLim');
                app.ScaleMaximumEditField.Visible = 1;
                app.ScaleMaximumEditFieldLabel.Visible = 1;
                app.ScaleMinimumEditField.Visible = 1;
                app.ScaleMinimumEditFieldLabel.Visible = 1;
                pos = app.ScaleMaximumEditField.Position;
                app.ScaleMaximumEditField.Position = [pos(1),pos(2),50,25];
                pos = app.ScaleMinimumEditField.Position;
                app.ScaleMinimumEditField.Position = [pos(1),pos(2),50,25];
                app.ScaleMaximumEditField.Value = rl(2);
                app.ScaleMinimumEditField.Value = rl(1);
            else
                app.ScaleMaximumEditField.Visible = 0;
                app.ScaleMaximumEditFieldLabel.Visible = 0;
                app.ScaleMinimumEditField.Visible = 0;
                app.ScaleMinimumEditFieldLabel.Visible = 0;
                app.Pax.RLimMode = 'auto';
            end
        end

        % Value changed function: SwitchRef
        function SwitchRefValueChanged(app, event)
            app.SwitchRef.Items = {'ref','all'};
            app.Type = app.SwitchRef.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WeightresultTab = uitab(app.TabGroup);
            app.WeightresultTab.Title = 'Weight result';
            app.ScaleMaximumEditFieldLabel = uilabel(app.WeightresultTab);
            app.ScaleMaximumEditFieldLabel.Visible = 'off';
            app.ScaleMaximumEditFieldLabel.Text = 'Scale maximum';
            app.ScaleMaximumEditField = uieditfield(app.WeightresultTab, 'numeric');
            app.ScaleMaximumEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleMaximumEditFieldValueChanged, true);
            app.ScaleMaximumEditField.Visible = 'off';
            app.ScaleMinimumEditFieldLabel = uilabel(app.WeightresultTab);
            app.ScaleMinimumEditFieldLabel.Visible = 'off';
            app.ScaleMinimumEditFieldLabel.Text = 'Scale minimum';
            app.ScaleMinimumEditField = uieditfield(app.WeightresultTab, 'numeric');
            app.ScaleMinimumEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleMinimumEditFieldValueChanged, true);
            app.ScaleMinimumEditField.Visible = 'off';
            app.AutoscaleSwitchLabel = uilabel(app.WeightresultTab);
            app.AutoscaleSwitchLabel.Text = 'Autoscale';
            app.AutoscaleSwitch = uiswitch(app.WeightresultTab, 'slider');
            app.AutoscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoscaleSwitchValueChanged, true);
            app.AutoscaleSwitch.Value = 'On';
            app.PhaseSliderLabel = uilabel(app.WeightresultTab);
            app.PhaseSliderLabel.Text = 'Phase';
            app.tWeightSlider = uislider(app.WeightresultTab);
            app.tWeightSlider.Limits = [0 360];
            app.tWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.WeightSliderLabel = uilabel(app.WeightresultTab);
            app.WeightSliderLabel.Text = 'Weight';
            app.mWeightSlider = uislider(app.WeightresultTab);
            app.mWeightSlider.Limits = [0 200];
            app.mWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.SwitchRef = uiswitch(app.WeightresultTab, 'slider');
            app.SwitchRef.Items = {'ref', 'all'};
            app.SwitchRef.ValueChangedFcn = createCallbackFcn(app, @SwitchRefValueChanged, true);
            app.SwitchRef.Value = 'all';
            app.ImportRawXlsFileButton = uibutton(app.WeightresultTab, 'push');
            app.ImportRawXlsFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRawXlsFileButtonPushed, true);
            app.ImportRawXlsFileButton.Text = 'Import raw xls-file';
            app.mWeightLabel = uilabel(app.WeightresultTab);
            app.mWeightLabel.Text = 'NaN';
            app.tWeightLabel = uilabel(app.WeightresultTab);
            app.tWeightLabel.Text = 'NaN';
            app.ImporttableTab = uitab(app.TabGroup);
            app.ImporttableTab.Title = 'Import table';
            app.ResulttableTab = uitab(app.TabGroup);
            app.ResulttableTab.Title = 'Result table';
            app.P2PplotTab = uitab(app.TabGroup);
            app.P2PplotTab.Title = 'P2P plot';
            app.WeightsplotTab = uitab(app.TabGroup);
            app.WeightsplotTab.Title = 'Weights plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_v1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end