classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        WeightresultTab       matlab.ui.container.Tab
        ScaleMaxFieldLbl      matlab.ui.control.Label
        ScaleMaxField         matlab.ui.control.NumericEditField
        ScaleMinFieldLbl      matlab.ui.control.Label
        ScaleMinField         matlab.ui.control.NumericEditField
        AutoscaleSwitchLabel  matlab.ui.control.Label
        AutoscaleSwitch       matlab.ui.control.Switch
        PhaseSliderLabel      matlab.ui.control.Label
        tWeightSlider         matlab.ui.control.Slider
        WeightSliderLabel     matlab.ui.control.Label
        mWeightSlider         matlab.ui.control.Slider
        SwitchRef             matlab.ui.control.Switch
        ImportDataButton      matlab.ui.control.Button
        mWeightLabel          matlab.ui.control.Label
        tWeightLabel          matlab.ui.control.Label
        ImporttableTab        matlab.ui.container.Tab
        ResulttableTab        matlab.ui.container.Tab
        P2PplotTab            matlab.ui.container.Tab
        WeightsplotTab        matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Pax % Resul polar plot
        Vax % Vibration polar plot
        Wax % Weight polar plot
        Data % Data   
        RawData % Data from xls
        FullData
        Box % Hint
        Type % All or reference
        uitImport % Description
    end
    
    methods (Access = private)
        
        function updateplot(app)
            global gcolor; setpath;
            mWeight = app.mWeightSlider.Value;
            tWeight = app.tWeightSlider.Value;
            zWeight = getcomplex(mWeight, tWeight);
            D = app.Data;
            modes = unique(D.modes);
            app.Pax.NextPlot = 'replace';
            for i = 1:length(modes)
                g = colormod(i);
                a = v_isakovich(D(i,:),zWeight);
                zP2P = getcomplex(D.mP2P(i),D.tP2P(i));
                points = arrowapp(zP2P,a);
                z = [linspace(zP2P,a,10e3),points];
                polarplot(app.Pax,z,'.', ...
                    'LineWidth',5, ...
                    'Color',gcolor(g,:));
                app.Pax.NextPlot = 'add';
            end
            hLegend = legend(app.Pax,cellstr(modes), ...
                'Position',[.25,.2,.1,.1]);
            set(hLegend,'Color','none','Box','off');
        end
        
        function vibrationplot(app)
            global gcolor; setpath;
            D = app.RawData;
            modes = unique(D.mode);
            app.Vax.NextPlot = 'replace';
            for i = 1:length(modes)
                g = colormod(i);
                m = D(D.mode == modes(i),:);
                z = [];
                for j = 1:height(m)
                    ref = m.ref(j);
                    if ref ~= -1
                        z = [z,linspace(m.zP2P(ref),m.zP2P(j),10e3)];
                        z = [z,arrowapp(m.zP2P(ref),m.zP2P(j))];
                    end
                end
                polarplot(app.Vax,z,'.', ...
                    'LineWidth',5, ...
                    'Color',gcolor(g,:));
                app.Vax.NextPlot = 'add';
                v_text(app.Vax, D, 'vibration');
            end
            hLegend = legend(app.Vax,cellstr(modes), ...
                'Position',[.2,.2,.1,.1]);
            set(hLegend,'Color','none','Box','off');
        end        
        
       function weightnplot(app)
            global gcolor; setpath;
            R = app.RawData;
            F = app.FullData;
            modes = unique(R.mode);
            app.Wax.NextPlot = 'replace';
            for i = 1:length(modes)
                g = colormod(i);
                m = R(R.mode == modes(i),:);
                z = [];
                for j = 1:height(m)
                    ref = m.ref(j);
                    if ref ~= -1
                        z = [z,linspace(m.zWeightSum(ref),m.zWeightSum(j),10e3)];
                        z = [z,arrowapp(m.zWeightSum(ref),m.zWeightSum(j))];
                    end
                end
                polarplot(app.Wax,z,'.', ...
                    'LineWidth',5, ...
                    'Color',gcolor(g,:));
                app.Wax.NextPlot = 'add';
                v_text(app.Wax, m, 'weight');
            end
            
            for i=1:length(modes)
                m = F(F.mode == modes(i), :);
                g = colormod(i);
                polarplot(app.Wax,m.zWeightTarget,'o', ...
                    'LineWidth',2, ...
                    'Color',gcolor(g,:));
            end
            
            hLegend = legend(app.Vax,cellstr(modes), ...
                'Position',[.2,.2,.1,.1]);
            set(hLegend,'Color','none','Box','off');
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filepath)
            setpath;
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];   
            % Create polars axes and position it in pixels
            app.Pax = polaraxes(app.WeightresultTab);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [150 40 width-220 height-120];
            app.Vax = polaraxes(app.P2PplotTab);
            app.Vax.Units = 'pixels';
            app.Vax.Position = [40 40 width-150 height-100];
            app.Type = 'all';
            app.Wax = polaraxes(app.WeightsplotTab);
            app.Wax.Units = 'pixels';
            app.Wax.Position = [40 40 width-150 height-100];
        end

        % Value changed function: mWeightSlider, tWeightSlider
        function SliderChange(app, event)
            app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
            app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');   
            if app.Box == 1
                updateplot(app);
            end
        end

        % Value changed function: ScaleMaxField
        function ScaleMaxFieldValueChanged(app, event)
            maximum = app.ScaleMaxField.Value;
            minimum = app.ScaleMinField.Value;
            if maximum > minimum
                rlim(app.Pax, [minimum maximum]);
            end
        end

        % Value changed function: ScaleMinField
        function ScaleMinFieldValueChanged(app, event)
            maximum = app.ScaleMaxField.Value;
            minimum = app.ScaleMinField.Value;
            if maximum > minimum
                rlim(app.Pax, [minimum maximum]);            
            end
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            setpath;
            [file, path] = uigetfile({'*.xls'});
            if ~isequal(file,0)
                screenSize = get(groot,'ScreenSize');
                screenWidth = screenSize(3);
                screenHeight = screenSize(4);
                width = screenWidth*0.8;
                height = screenHeight*0.8;
                R = imxls(fullfile(path,file));
                T = pullcorrectweight(R,app.Type);
                app.Data = sumsen(T);
                app.RawData = R; 
                app.FullData = T; 
                app.mWeightSlider.Limits = [0 ceil(2*app.Data.mWeightTarget(1))];
                app.mWeightSlider.Value = app.Data.mWeightTarget(1);
                app.mWeightLabel.Text = num2str(app.mWeightSlider.Value,'%.1f');
                app.tWeightSlider.Value = wrapTo360(app.Data.tWeightTarget(1));
                app.tWeightLabel.Text = num2str(app.tWeightSlider.Value,'%.0f');
                app.Box = 1;
                updateplot(app); 
                uitraw = uitable(app.ImporttableTab,"Data",R, ...
                    "Position", [50 50 width-100 height-100]);
                uitable(app.ResulttableTab,"Data",T,"Position",[50 50 width-100 height-100]);
                vibrationplot(app);
                weightnplot(app);
            end  
        end

        % Value changed function: AutoscaleSwitch
        function AutoscaleSwitchValueChanged(app, event)
            value = app.AutoscaleSwitch.Value;
            if value ~= "On"
                rl = get(app.Pax, 'RLim');
                visible(1);
                pos = app.ScaleMaxField.Position;
                app.ScaleMaxField.Position = [pos(1),pos(2),50,25];
                pos = app.ScaleMinField.Position;
                app.ScaleMinField.Position = [pos(1),pos(2),50,25];
                app.ScaleMaxField.Value = rl(2);
                app.ScaleMinField.Value = rl(1);
            else
                visible(0);
                app.Pax.RLimMode = 'auto';
            end
            function visible(tf)
                app.ScaleMaxField.Visible = tf;
                app.ScaleMaxFieldLbl.Visible = tf;
                app.ScaleMinField.Visible = tf;
                app.ScaleMinFieldLbl.Visible = tf;
            end 
        end

        % Value changed function: SwitchRef
        function SwitchRefValueChanged(app, event)
            app.SwitchRef.Items = {'ref','all'};
            app.Type = app.SwitchRef.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WeightresultTab = uitab(app.TabGroup);
            app.WeightresultTab.Title = 'Weight result';
            app.ScaleMaxFieldLbl = uilabel(app.WeightresultTab);
            app.ScaleMaxFieldLbl.Visible = 'off';
            app.ScaleMaxFieldLbl.Text = 'Scale maximum';
            app.ScaleMaxField = uieditfield(app.WeightresultTab, 'numeric');
            app.ScaleMaxField.ValueChangedFcn = createCallbackFcn(app, @ScaleMaxFieldValueChanged, true);
            app.ScaleMaxField.Visible = 'off';
            app.ScaleMinFieldLbl = uilabel(app.WeightresultTab);
            app.ScaleMinFieldLbl.Visible = 'off';
            app.ScaleMinFieldLbl.Text = 'Scale minimum';
            app.ScaleMinField = uieditfield(app.WeightresultTab, 'numeric');
            app.ScaleMinField.ValueChangedFcn = createCallbackFcn(app, @ScaleMinFieldValueChanged, true);
            app.ScaleMinField.Visible = 'off';
            app.AutoscaleSwitchLabel = uilabel(app.WeightresultTab);
            app.AutoscaleSwitchLabel.Text = 'Autoscale';
            app.AutoscaleSwitch = uiswitch(app.WeightresultTab, 'slider');
            app.AutoscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoscaleSwitchValueChanged, true);
            app.AutoscaleSwitch.Value = 'On';
            app.PhaseSliderLabel = uilabel(app.WeightresultTab);
            app.PhaseSliderLabel.Text = 'Phase';
            app.tWeightSlider = uislider(app.WeightresultTab);
            app.tWeightSlider.Limits = [0 360];
            app.tWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.WeightSliderLabel = uilabel(app.WeightresultTab);
            app.WeightSliderLabel.Text = 'Weight';
            app.mWeightSlider = uislider(app.WeightresultTab);
            app.mWeightSlider.Limits = [0 200];
            app.mWeightSlider.ValueChangedFcn = createCallbackFcn(app, @SliderChange, true);
            app.SwitchRef = uiswitch(app.WeightresultTab, 'slider');
            app.SwitchRef.Items = {'ref', 'all'};
            app.SwitchRef.ValueChangedFcn = createCallbackFcn(app, @SwitchRefValueChanged, true);
            app.SwitchRef.Value = 'all';
            app.ImportDataButton = uibutton(app.WeightresultTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import raw xls-file';
            app.mWeightLabel = uilabel(app.WeightresultTab);
            app.mWeightLabel.Text = 'NaN';
            app.tWeightLabel = uilabel(app.WeightresultTab);
            app.tWeightLabel.Text = 'NaN';
            app.ImporttableTab = uitab(app.TabGroup);
            app.ImporttableTab.Title = 'Import table';
            app.ResulttableTab = uitab(app.TabGroup);
            app.ResulttableTab.Title = 'Result table';
            app.P2PplotTab = uitab(app.TabGroup);
            app.P2PplotTab.Title = 'P2P plot';
            app.WeightsplotTab = uitab(app.TabGroup);
            app.WeightsplotTab.Title = 'Weights plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end